classdef GlobalCovidApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        DatatoPlotButtonGroup    matlab.ui.container.ButtonGroup
        CasesButton              matlab.ui.control.RadioButton
        DeathsButton             matlab.ui.control.RadioButton
        BothButton               matlab.ui.control.RadioButton
        DataPatternButtonGroup   matlab.ui.container.ButtonGroup
        CumulativeButton         matlab.ui.control.RadioButton
        DailyButton              matlab.ui.control.RadioButton
        DataAnalysisSliderLabel  matlab.ui.control.Label
        DataAnalysisSlider       matlab.ui.control.Slider
        CountryListBoxLabel      matlab.ui.control.Label
        CountryListBox           matlab.ui.control.ListBox
        StateRegionListBoxLabel  matlab.ui.control.Label
        StateRegionListBox       matlab.ui.control.ListBox
        RightPanel               matlab.ui.container.Panel
        UIAxes                   matlab.ui.control.UIAxes
        ResetAllButton           matlab.ui.control.Button
        ReadmeTextArea           matlab.ui.control.TextArea
        ReadmeTextAreaLabel      matlab.ui.control.Label
        UpdatePlotButton         matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
       state;
       
       Dates;
       DatesNum;       
       
       cumulativecases;
       cumulativedeaths;
       dailycases;
       dailydeaths;
       
       country;
       cases;
       deaths;
       
       allcountries;
       allstates;
       allcases;
       alldeaths;
       
       averagedailycases;
       averagedailydeaths;
       averagecumulativecases;
       averagecumulativedeaths;
       
       ShowCases;
       ShowDeaths;
    end       
    
    methods (Access = public)
                
        function PlotData(app)
            
            if app.state=="All"
                graphlabel=app.country;
%                 testcountry=app.country
            else
                graphlabel=app.state;
%                 teststate=app.state
            end     
            
            graphlabel1 = app.DataPatternButtonGroup.SelectedObject.Text;
            
            if app.DataAnalysisSlider.Value > 1
                graphlabel2 = ['(' num2str(app.DataAnalysisSlider.Value) ' day mean)'];
            else
                graphlabel2 = '';
            end
            
               cla(app.UIAxes,"reset");
               
              if app.ShowCases && app.ShowDeaths
                cla(app.UIAxes,"reset");
                yyaxis(app.UIAxes,'left');
                bar(app.UIAxes,app.DatesNum,round(app.cases));
                datetick(app.UIAxes,'x','mmm/yy', 'keepticks', 'keeplimits'); 
                ylabel(app.UIAxes,{'Cases';''});
%                 yticks(app.UIAxes,'auto');
                
                yyaxis(app.UIAxes,'right');
                plot(app.UIAxes,app.DatesNum,round(app.deaths));
                datetick(app.UIAxes,'x','mmm/yy', 'keepticks', 'keeplimits'); 
                ylabel(app.UIAxes,{'';'Deaths'});
%                 yticks(app.UIAxes,'auto');
                
                Y = app.UIAxes;
                Y.Title.String = ([graphlabel ' ' graphlabel1 ' Number of Cases and Deaths ' graphlabel2]);
                Y.FontSize = 8;
%                 Y.Title.String.FontSize = 12;
                Y.TitleFontSizeMultiplier =1.25;
                
                Y.YAxis(1).TickLabelFormat='%,d';
                Y.YAxis(1).Exponent = 0;
                Y.YAxis(1).Limits=[0 max(Y.YAxis(1).Limits)];
                Y.YAxis(1).TickValues=Y.YAxis(1).TickValues(abs(round(Y.YAxis(1).TickValues))==Y.YAxis(1).TickValues);
                                
                Y.YAxis(2).TickLabelFormat='%,d';
                Y.YAxis(2).Exponent = 0;                                
                Y.YAxis(2).Limits=[0 max(Y.YAxis(2).Limits)];
                Y.YAxis(2).TickValues=Y.YAxis(2).TickValues(abs(round(Y.YAxis(2).TickValues))==Y.YAxis(2).TickValues);
                
                Y.YAxis(1).Visible='on';
                Y.YAxis(2).Visible='on';
                
                xlabel(app.UIAxes,{'';'Dates(Month/Year)'});
              end 
              
              if app.ShowCases && ~app.ShowDeaths
                cla(app.UIAxes,"reset");
                yyaxis(app.UIAxes,'left');
                bar(app.UIAxes,app.DatesNum,round(app.cases));
                datetick(app.UIAxes,'x','mmm/yy', 'keepticks', 'keeplimits'); 
                ylabel(app.UIAxes,{'Cases';''});
                
                Y = app.UIAxes;
                Y.Title.String = ([graphlabel ' ' graphlabel1 ' Number of Cases ' graphlabel2]);
                Y.FontSize = 8;
%                 Y.Title.String.FontSize = 12;
                Y.TitleFontSizeMultiplier =1.25;
                
                Y.YAxis(1).TickLabelFormat='%,d';
                Y.YAxis(1).Exponent = 0;
                Y.YAxis(1).Limits=[0 max(Y.YAxis(1).Limits)];
                Y.YAxis(1).TickValues=Y.YAxis(1).TickValues(abs(round(Y.YAxis(1).TickValues))==Y.YAxis(1).TickValues);
                
                Y.YAxis(1).Visible='on';
                Y.YAxis(2).Visible='off';
                
                xlabel(app.UIAxes,{'';'Dates(Month/Year)'});
              end
% 
              if app.ShowDeaths && ~app.ShowCases 
                cla(app.UIAxes,"reset");
                yyaxis(app.UIAxes,'right');
                plot(app.UIAxes,app.DatesNum,round(app.deaths));
                datetick(app.UIAxes,'x','mmm/yy', 'keepticks', 'keeplimits'); 
                ylabel(app.UIAxes,{'';'Deaths'});
                
                Y = app.UIAxes;
                Y.Title.String = ([graphlabel ' ' graphlabel1 ' Number of Deaths ' graphlabel2]);
                Y.FontSize = 8;
%                 Y.Title.String.FontSize = 12;
                Y.TitleFontSizeMultiplier =1.25;
                
                Y.YAxis(2).TickLabelFormat='%,d';
                Y.YAxis(2).Exponent = 0;                                
                Y.YAxis(2).Limits=[0 max(Y.YAxis(2).Limits)];
                Y.YAxis(2).TickValues=Y.YAxis(2).TickValues(abs(round(Y.YAxis(2).TickValues))==Y.YAxis(2).TickValues);
                
                Y.YAxis(1).Visible='off';
                Y.YAxis(2).Visible='on';
                
                
                xlabel(app.UIAxes,{'';'Dates(Month/Year)'});
              end

        end
        
        function SetDefaults(app)

            app.CountryListBox.Value='Global';
            app.StateRegionListBox.Value='All';
            app.ShowCases=app.CasesButton.Value || app.BothButton.Value;
            app.ShowDeaths=app.DeathsButton.Value || app.BothButton.Value;
            
            app.BothButton.Value=1;
            app.CumulativeButton.Value=1;
            app.DataAnalysisSlider.Value=1;            
            
%83 97 106 105 118 77 97 116 104 101 119 
%Default set and plot
            app.country=app.country{1,1};
            app.StateRegionListBox.Items = app.state(1,1); 
            app.state=app.state{1,1};
            app.cases=app.cumulativecases(1,:);
            app.deaths=app.cumulativedeaths(1,:);              
        end
        
        function ResetValues(app)
%Global reset                        
            global rlimit;
            global climit;
            global actualindex;
            global actualfinalindex;
             
%             actualindex=1;
%             actualfinalindex=1;

            app.cumulativecases=reshape(app.allcases,[rlimit, climit]);
            app.cumulativecases = [zeros(1,climit);app.cumulativecases];

            app.cumulativedeaths=reshape(app.alldeaths,[rlimit, climit]);
            app.cumulativedeaths = [zeros(1,climit);app.cumulativedeaths];
            

            app.country=['Global';app.allcountries];
            app.CountryListBox.Items = ['Global';unique(app.allcountries)]; 
            
%             app.state = ['All';app.allstates];
%             app.state(strcmp('',app.state)) = {'All'};
%             app.StateRegionListBox.Items = [app.state(1,1)]; 
            
            app.state = ['All';app.allstates];
            app.state(strcmp('',app.state)) = {'All'};
            app.StateRegionListBox.Items = [app.state(actualindex:actualfinalindex,1)]; 

            
            for k=climit:-1:1
                    app.cumulativecases(1,k)=sum(app.cumulativecases(:,k));
                    app.cumulativedeaths(1,k)=sum(app.cumulativedeaths(:,k));
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load covid_data.mat covid_data;
            inputdata=covid_data(2:end,:);

            global cgroups;
            global cnames;
            global snames;
            global ssubdata;
            global j;
            global k;
            global rlimit;
            global climit;
            global actualindex;
            global actualfinalindex;

            actualindex=1;
            actualfinalindex=1;
            
            cgroups=[1;findgroups(inputdata(:,1))+1];
            cnames=(inputdata(:,1));
            
            snames=inputdata(:,2);
            ssubdata=inputdata(:,3:end);
            [rlimit, climit]=size(ssubdata);
                    
            for j=rlimit:-1:1
                for k=climit:-1:1 
                    GlobalObject(j,k) = globalclass(cnames(j,1),snames(j,1),ssubdata{j,k}(1,1),ssubdata{j,k}(1,2));
                end
            end  

%Local set
%83 97 106 105 118 77 97 116 104 101 119 
            app.Dates = covid_data(1,3:end);    
            app.DatesNum = (datenum(app.Dates, 'mm/dd/yy'))';
            app.allcases=[GlobalObject(:,:).cases];
            app.alldeaths=[GlobalObject(:,:).deaths];
            app.allcountries=[GlobalObject(:,1).country]';
            app.allstates=[GlobalObject(:,1).state]';
            
%Global reset            
            app.ResetValues();

%Default set 
            app.SetDefaults();
%Plot             
            app.PlotData();
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {547, 547};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {217, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Selection changed function: DatatoPlotButtonGroup
        function DatatoPlotButtonGroupSelectionChanged(app, event)
%             selectedButton = app.DatatoPlotButtonGroup.SelectedObject
            global actualindex;
            global actualfinalindex;
            global stateindex;
            global cgroups;

%Global Reset   
            app.ResetValues();
%             app.DataAnalysisSlider.Value=1;

            app.ShowCases=app.CasesButton.Value||app.BothButton.Value;
            app.ShowDeaths=app.DeathsButton.Value||app.BothButton.Value;

            index = min(find(strcmp(app.CountryListBox.Items, app.CountryListBox.Value)));
            actualindex = min(find(cgroups==index))
            actualfinalindex = max(find(cgroups==index))
            stateindex = find(strcmp(app.StateRegionListBox.Items, app.StateRegionListBox.Value))

if app.DataPatternButtonGroup.SelectedObject==app.DailyButton 
    if app.StateRegionListBox.Value == "All"
%         testcountry=1
            app.country=app.country{actualindex,1};
            app.cases=app.dailycases(actualindex,:);
            app.deaths=app.dailydeaths(actualindex,:);
    else 
%         teststate=1
            app.state=app.state{actualindex+stateindex-1,1};
            app.cases=app.dailycases(actualindex+stateindex-1,:);
            app.deaths=app.dailydeaths(actualindex+stateindex-1,:);
    end 
end            

if app.DataPatternButtonGroup.SelectedObject==app.CumulativeButton
    if app.StateRegionListBox.Value == "All"
%         testcountry=1
            app.country=app.country{actualindex,1};
            app.cases=app.cumulativecases(actualindex,:);
            app.deaths=app.cumulativedeaths(actualindex,:);
    else        
%         teststate=1
            app.state=app.state{actualindex+stateindex-1,1};
            app.cases=app.cumulativecases(actualindex+stateindex-1,:);
            app.deaths=app.cumulativedeaths(actualindex+stateindex-1,:);
    end 
end

            app.state = ['All';app.allstates];
            app.state(strcmp('',app.state)) = {'All'};
            app.StateRegionListBox.Items = [app.state(actualindex:actualfinalindex,1)]; 
            app.state=app.state{actualindex+stateindex-1,1};
            
%             app.cases=app.dailycases(actualindex+stateindex-1,:);
%             app.deaths=app.dailydeaths(actualindex+stateindex-1,:);
%             app.PlotData();
           
%Global Reset   
%             app.ResetValues();
        end

        % Selection changed function: DataPatternButtonGroup
        function DataPatternButtonGroupSelectionChanged(app, event)
%             selectedButton = app.DataPatternButtonGroup.SelectedObject
            global cgroups;
            global actualindex;
            global actualfinalindex;
            global stateindex;
            global rlimit;
            global climit;
            
            
%Global Reset   
            app.ResetValues();
%             app.DataAnalysisSlider.Value=1;
            
            for j=rlimit+1:-1:1
                for k=climit:-1:2
                   app.dailycases(j,k)=max(0,(app.cumulativecases(j,k)-app.cumulativecases(j,k-1))); 
                   app.dailydeaths(j,k)=max(0,(app.cumulativedeaths(j,k)-app.cumulativedeaths(j,k-1)));  
                end
                app.dailycases(j,1)=app.cumulativecases(j,1); 
                app.dailydeaths(j,1)=app.cumulativedeaths(j,1);
            end
            
            index = min(find(strcmp(app.CountryListBox.Items, app.CountryListBox.Value)));
            actualindex = min(find(cgroups==index))
            actualfinalindex = max(find(cgroups==index))
            stateindex = find(strcmp(app.StateRegionListBox.Items, app.StateRegionListBox.Value))
            
            
%Index country 
if app.DataPatternButtonGroup.SelectedObject==app.DailyButton 
        if app.StateRegionListBox.Value == "All"
%         testcountry=1
            app.country=app.country{actualindex,1};
            app.cases=app.dailycases(actualindex,:);
            app.deaths=app.dailydeaths(actualindex,:);
        else        
%         teststate=1
            app.state=app.state{actualindex+stateindex-1,1};
            app.cases=app.dailycases(actualindex+stateindex-1,:);
            app.deaths=app.dailydeaths(actualindex+stateindex-1,:);
        end
end            

if app.DataPatternButtonGroup.SelectedObject==app.CumulativeButton            
        if app.StateRegionListBox.Value == "All"
%         testcountry=1
            app.country=app.country{actualindex,1};
            app.cases=app.cumulativecases(actualindex,:);
            app.deaths=app.cumulativedeaths(actualindex,:);
        else        
%         teststate=1
            app.state=app.state{actualindex+stateindex-1,1};
            app.cases=app.cumulativecases(actualindex+stateindex-1,:);
            app.deaths=app.cumulativedeaths(actualindex+stateindex-1,:);
        end
end
            
            app.state = ['All';app.allstates];
            app.state(strcmp('',app.state)) = {'All'};
            app.StateRegionListBox.Items = [app.state(actualindex:actualfinalindex,1)]; 
            app.state=app.state{actualindex+stateindex-1,1};
%             app.PlotData();            
            
%Global Reset   
%             app.ResetValues();            

        end

        % Value changed function: DataAnalysisSlider
        function DataAnalysisSliderValueChanged(app, event)
            value = round(app.DataAnalysisSlider.Value)
            app.DataAnalysisSlider.Value = round(app.DataAnalysisSlider.Value);
            global cgroups;
            global actualindex;
            global actualfinalindex;
            global stateindex;
            global rlimit;
            global climit;  
            global j;
            global k;

%Global Reset   
            app.ResetValues();
            app.averagedailycases=zeros(1,climit);
            app.averagedailydeaths=zeros(1,climit);

            app.averagecumulativecases=zeros(1,climit);
            app.averagecumulativedeaths=zeros(1,climit);
            
            index = min(find(strcmp(app.CountryListBox.Items, app.CountryListBox.Value)));
            actualindex = min(find(cgroups==index))
            actualfinalindex = max(find(cgroups==index))
            stateindex = find(strcmp(app.StateRegionListBox.Items, app.StateRegionListBox.Value))
            
%Index country 
if app.DataPatternButtonGroup.SelectedObject==app.DailyButton 
        if app.StateRegionListBox.Value == "All"
        testcountry=1
            for j=actualindex;
                for k=climit:-1:value
                   app.averagedailycases(j,k)=round(sum(app.dailycases(j,k-value+1:k))/value); 
                   app.averagedailydeaths(j,k)=round(sum(app.dailydeaths(j,k-value+1:k))/value);  
            
                end
            end    
            app.country=app.country{actualindex,1};
            app.cases=app.averagedailycases(actualindex,:);
            app.deaths=app.averagedailydeaths(actualindex,:);
        
        else 
            teststate=1
            
            for j=actualindex+stateindex-1;
                for k=climit:-1:value
                   app.averagedailycases(j,k)=round(sum(app.dailycases(j,k-value+1:k))/value); 
                   app.averagedailydeaths(j,k)=round(sum(app.dailydeaths(j,k-value+1:k))/value);  
            
                end
            end
            app.state=app.state{actualindex+stateindex-1,1};
            app.cases=app.averagedailycases(actualindex+stateindex-1,:);
            app.deaths=app.averagedailydeaths(actualindex+stateindex-1,:);
        end 
            
end            

%83 97 106 105 118 77 97 116 104 101 119 
if app.DataPatternButtonGroup.SelectedObject==app.CumulativeButton
    if app.StateRegionListBox.Value == "All"
        testcountry=1
            for j=actualindex;
                for k=climit:-1:value
                   app.averagecumulativecases(j,k)=round(sum(app.cumulativecases(j,k-value+1:k))/value); 
                   app.averagecumulativedeaths(j,k)=round(sum(app.cumulativedeaths(j,k-value+1:k))/value);  
            
                end
            end

            app.country=app.country{actualindex,1};
            app.cases=app.averagecumulativecases(actualindex,:);
            app.deaths=app.averagecumulativedeaths(actualindex,:); 
            
    else
        teststate=1
            for j=actualindex+stateindex-1;
                for k=climit:-1:value
                   app.averagecumulativecases(j,k)=round(sum(app.cumulativecases(j,k-value+1:k))/value); 
                   app.averagecumulativedeaths(j,k)=round(sum(app.cumulativedeaths(j,k-value+1:k))/value);  
            
                end
            end
        
            app.state=app.state{actualindex+stateindex-1,1};
            app.cases=app.averagecumulativecases(actualindex+stateindex-1,:);
            app.deaths=app.averagecumulativedeaths(actualindex+stateindex-1,:);
        
    end 

end

            app.state = ['All';app.allstates];
            app.state(strcmp('',app.state)) = {'All'};
            app.StateRegionListBox.Items = [app.state(actualindex:actualfinalindex,1)]; 
            app.state=app.state{actualindex+stateindex-1,1};
            
%             app.PlotData();            
            
%Global Reset   
%             app.ResetValues();            
            
        end

        % Value changed function: CountryListBox
        function CountryListBoxValueChanged(app, event)
         
            global cgroups;
            global actualindex;
            global actualfinalindex;

%Global Reset               
            app.ResetValues();
            app.DataAnalysisSlider.Value=1;
            
            index = min(find(strcmp(app.CountryListBox.Items, app.CountryListBox.Value)));
            actualindex = min(find(cgroups==index))
            actualfinalindex = max(find(cgroups==index))
            
if app.DataPatternButtonGroup.SelectedObject==app.DailyButton 
            app.country=app.country{actualindex,1};
            app.cases=app.dailycases(actualindex,:);
            app.deaths=app.dailydeaths(actualindex,:);
end            

if app.DataPatternButtonGroup.SelectedObject==app.CumulativeButton
%             app.DataAnalysisSlider.Value=1;
            app.country=app.country{actualindex,1};
            app.cases=app.cumulativecases(actualindex,:);
            app.deaths=app.cumulativedeaths(actualindex,:);
end

            app.state = ['All';app.allstates];
            app.state(strcmp('',app.state)) = {'All'};
            app.StateRegionListBox.Items = [app.state(actualindex:actualfinalindex,1)]; 
            app.state=app.state{actualindex,1};
            
%         app.PlotData();
        
%Global Reset   
%             app.ResetValues();

        end

        % Value changed function: StateRegionListBox
        function StateRegionListBoxValueChanged(app, event)
%             value = app.StateRegionListBox.Value;
            global stateindex;
            global actualindex;
            global actualfinalindex;
            global cgroups;

%Global Reset               
            app.ResetValues();
            app.DataAnalysisSlider.Value=1;
            
            index = min(find(strcmp(app.CountryListBox.Items, app.CountryListBox.Value)));
            actualindex = min(find(cgroups==index));
            actualfinalindex = max(find(cgroups==index));
            
            actualindex;
            actualfinalindex;
            app.country=app.country{actualindex,1};
            
            stateindex = find(strcmp(app.StateRegionListBox.Items, app.StateRegionListBox.Value))
%             app.state=app.state(actualindex+stateindex-1,1);
            
if app.DataPatternButtonGroup.SelectedObject==app.DailyButton 
            app.state=app.state{actualindex+stateindex-1,1};
            app.cases=app.dailycases(actualindex+stateindex-1,:);
            app.deaths=app.dailydeaths(actualindex+stateindex-1,:);
end            

if app.DataPatternButtonGroup.SelectedObject==app.CumulativeButton
%             app.DataAnalysisSlider.Value=1;
            app.state=app.state{actualindex+stateindex-1,1};
            app.cases=app.cumulativecases(actualindex+stateindex-1,:);
            app.deaths=app.cumulativedeaths(actualindex+stateindex-1,:);
end

%             app.state = ['All';app.allstates];
%             app.state(strcmp('',app.state)) = {'All'};
%             app.StateRegionListBox.Items = [app.state(actualindex:actualfinalindex,1)]; 

%             app.PlotData();
%             app.ResetValues();
        end

        % Button pushed function: UpdatePlotButton
        function UpdatePlot(app, event)
                       
            app.PlotData();
%             app.ResetValues();
        end

        % Button pushed function: ResetAllButton
        function ResetAllButtonPushed(app, event)
            app.BothButton.Value=1;
            app.CumulativeButton.Value=1;
            app.DataAnalysisSlider.Value=1;

%Global reset            
            app.ResetValues();

%Default set 
            app.SetDefaults();
            
%Plot             
            app.PlotData();            

%             app.startupFcn();

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DatatoPlotButtonGroup = uibuttongroup(app.LeftPanel);
            app.DatatoPlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatatoPlotButtonGroupSelectionChanged, true);
            app.DatatoPlotButtonGroup.Title = 'Data to Plot';
            app.CasesButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.DeathsButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.DeathsButton.Text = 'Deaths';
            app.BothButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.BothButton.Text = 'Both';
            app.BothButton.Value = true;
            app.DataPatternButtonGroup = uibuttongroup(app.LeftPanel);
            app.DataPatternButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DataPatternButtonGroupSelectionChanged, true);
            app.DataPatternButtonGroup.Title = 'Data Pattern';
            app.CumulativeButton = uiradiobutton(app.DataPatternButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.DataPatternButtonGroup);
            app.DailyButton.Text = 'Daily';
            app.DataAnalysisSliderLabel = uilabel(app.LeftPanel);
            app.DataAnalysisSliderLabel.Text = 'Data Analysis';
            app.DataAnalysisSlider = uislider(app.LeftPanel);
            app.DataAnalysisSlider.Limits = [1 15];
            app.DataAnalysisSlider.MajorTicks = [1 3 5 7 9 11 13 15];
            app.DataAnalysisSlider.MajorTickLabels = {' 1 day mean ', ' 3 day mean', ' 5 day mean', ' 7 day mean', ' 9 day mean', '11 day mean', '13 day mean', '15 day mean'};
            app.DataAnalysisSlider.ValueChangedFcn = createCallbackFcn(app, @DataAnalysisSliderValueChanged, true);
            app.DataAnalysisSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15];
            app.DataAnalysisSlider.Value = 1;
            app.CountryListBoxLabel = uilabel(app.LeftPanel);
            app.CountryListBoxLabel.Text = 'Country';
            app.CountryListBox = uilistbox(app.LeftPanel);
            app.CountryListBox.Items = {};
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @CountryListBoxValueChanged, true);
            app.CountryListBox.Value = {};
            app.StateRegionListBoxLabel = uilabel(app.LeftPanel);
            app.StateRegionListBoxLabel.Text = 'State/Region';
            app.StateRegionListBox = uilistbox(app.LeftPanel);
            app.StateRegionListBox.Items = {};
            app.StateRegionListBox.ValueChangedFcn = createCallbackFcn(app, @StateRegionListBoxValueChanged, true);
            app.StateRegionListBox.Value = {};
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Daily number of cases and deaths')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.ResetAllButton = uibutton(app.RightPanel, 'push');
            app.ResetAllButton.ButtonPushedFcn = createCallbackFcn(app, @ResetAllButtonPushed, true);
            app.ResetAllButton.Text = 'Reset All';
            app.ReadmeTextArea = uitextarea(app.RightPanel);
            app.ReadmeTextArea.Value = {'1. Select inputs on the left'; '2. Click on the Update Plot button  '; '3. For Global Data, click on the Reset All button '; '4. All data obtained from the Coronavirus Resource Center at the Johns Hopkins University. Only Australia, Canada, China, Denmark, France, Netherlands, United Kingdom, United States have State/Region subdata in the database'; ''; ''};
            app.ReadmeTextAreaLabel = uilabel(app.RightPanel);
            app.ReadmeTextAreaLabel.Text = 'Readme';
            app.UpdatePlotButton = uibutton(app.RightPanel, 'push');
            app.UpdatePlotButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatePlot, true);
            app.UpdatePlotButton.Text = 'Update Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GlobalCovidApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end